classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LoadImageButton      matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        SelectpointsButton   matlab.ui.control.Button
        InfoButton           matlab.ui.control.Button
        SegmentsButtonGroup  matlab.ui.container.ButtonGroup
        Button_1             matlab.ui.control.ToggleButton
        Button_2             matlab.ui.control.ToggleButton
        Button_3             matlab.ui.control.ToggleButton
        Button_4             matlab.ui.control.ToggleButton
        Button_5             matlab.ui.control.ToggleButton
        Button_7             matlab.ui.control.ToggleButton
        Button_8             matlab.ui.control.ToggleButton
        Button_9             matlab.ui.control.ToggleButton
        Button_6             matlab.ui.control.ToggleButton
        Button_10            matlab.ui.control.ToggleButton
        TabGroup             matlab.ui.container.TabGroup
        DataanalysisTab      matlab.ui.container.Tab
        HistogramTab         matlab.ui.container.Tab
    end


    properties (Access = public)
        InputImage % Description
    end


    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
             warning('off','MATLAB:imagesci:tifftagsread:badTagValueDivisionByZero')
            
            [file,path] = uigetfile("*.jpg", 'Select a FLIR Image File');
            % workaround
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            fullpath = fullfile(path,file);

            I = importdata(fullpath);
            app.InputImage = I;
            gray = rgb2gray(I);
     
            imagesc(app.UIAxes, gray);
            xlim(app.UIAxes, [0 size(gray,2)]);
            ylim(app.UIAxes, [0 size(gray,1)]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Obraz FLIR')
            app.SelectpointsButton = uibutton(app.UIFigure, 'push');
            app.SelectpointsButton.Text = 'Select points';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.Text = 'Info';
            app.SegmentsButtonGroup = uibuttongroup(app.UIFigure);
            app.SegmentsButtonGroup.Title = 'Segments';
            app.Button_1 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_1.Text = '1';
            app.Button_1.Value = true;
            app.Button_2 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_4.Text = '4';
            app.Button_5 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_5.Text = '5';
            app.Button_7 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_7.Text = '7';
            app.Button_8 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_8.Text = '8';
            app.Button_9 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_9.Text = '9';
            app.Button_6 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_6.Text = '6';
            app.Button_10 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_10.Text = '10';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataanalysisTab = uitab(app.TabGroup);
            app.DataanalysisTab.Title = 'Data analysis';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end